classdef logpose_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        HOMETab                 matlab.ui.container.Tab
        run_IQ_button           matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        UPLOADIQFILEButton      matlab.ui.control.Button
        ATOOLTOPOSTPROCESSYOUROWNPOSITIONLabel  matlab.ui.control.Label
        StartbyuploadingaIQfileontherightLabel  matlab.ui.control.Label
        WELCOMETOLOGPOSELabel   matlab.ui.control.Label
        IQTab                   matlab.ui.container.Tab
        run_acquisition_button  matlab.ui.control.Image
        IQCharacteristicsLabel  matlab.ui.control.Label
        hist_I                  matlab.ui.control.UIAxes
        hist_Q                  matlab.ui.control.UIAxes
        time_I                  matlab.ui.control.UIAxes
        time_Q                  matlab.ui.control.UIAxes
        iq_frq                  matlab.ui.control.UIAxes
        ACQUISITIONTab          matlab.ui.container.Tab
        run_tracking_button     matlab.ui.control.Image
        SatelliteSignalAcquisitionLabel  matlab.ui.control.Label
        SatAcq_12               matlab.ui.control.UIAxes
        SatAcq_11               matlab.ui.control.UIAxes
        SatAcq_10               matlab.ui.control.UIAxes
        SatAcq_9                matlab.ui.control.UIAxes
        SatAcq_8                matlab.ui.control.UIAxes
        SatAcq_7                matlab.ui.control.UIAxes
        SatAcq_6                matlab.ui.control.UIAxes
        SatAcq_5                matlab.ui.control.UIAxes
        SatAcq_4                matlab.ui.control.UIAxes
        SatAcq_3                matlab.ui.control.UIAxes
        SatAcq_2                matlab.ui.control.UIAxes
        SatAcq                  matlab.ui.control.UIAxes
        TRACKINGTab             matlab.ui.container.Tab
        SATELLITEPOSTab         matlab.ui.container.Tab
        USERPOSTab              matlab.ui.container.Tab
    end

    properties (Access = public)
        nav; % NavTek class
        iq_file;
        acq_results_visual = cell(12);
        PRN_visual = cell(12);
    end
        
    
    methods (Access = private)
       
        function probeData(app)
           
            %% Generate and plot IQ Characteristics
            fid = fopen(app.nav.fnameIQ, 'rb');

            if (fid > 0)
                %move to the start of the where we want to start post
                %processing
                fseek(fid, app.nav.skipNumBytes, 'bof');

                %find the number of samples per chipping sequence
                sampsPerCode = round(app.nav.fsamp / (app.nav.fchip / app.nav.codeLength));

                %read in the data 
                [data, count] = fread(fid, [1, 2 * 100 * sampsPerCode], app.nav.dataType);

                fclose(fid);

                if (count < 2 * 100 * sampsPerCode)
                    error("Error not enough data to load in");
                end

                %initialize plots
                timeScale = 0: 1/app.nav.fsamp : 5e-3; 
                
                %% Time domain plot
                data=data(1:2:end) + 1i .* data(2:2:end);                
                plot(app.time_I, 1000 * timeScale(1:round(sampsPerCode/2)), real(data(1:round(sampsPerCode/2)))); % Time Plot for I
                plot(app.time_Q, 1000 * timeScale(1:round(sampsPerCode/2)), imag(data(1:round(sampsPerCode/2)))); % Time Plot for Q

                %% Frequency domain plot
                [sigspec,freqv]=pwelch(data, 32768, 2048, 32768, app.nav.fsamp,'twosided');
                plot(app.iq_frq,([-(freqv(length(freqv)/2:-1:1));freqv(1:length(freqv)/2)])/1e6, 10*log10([sigspec(length(freqv)/2+1:end);
                sigspec(1:length(freqv)/2)]));

                %% Histogram
                histogram(app.hist_I,real(data), -128:128)
%                 dmax = max(abs(data)) + 1;
%                 axis tight;     adata = axis;
%                 axis(app.hist_I,[-dmax dmax adata(3) adata(4)]);
                
                histogram(app.hist_Q,imag(data), -128:128)
%                 dmax = max(abs(data)) + 1;
%                 axis tight;     adata = axis;
%                 axis(app.hist_Q,[-dmax dmax adata(3) adata(4)]);
               
            end
        end


        function satellite_acquired_generate(app)
            mesh(app.SatAcq,app.acq_results_visual{1});
%             grid on;
            title(app.SatAcq,strcat(['C/A for PRN ' int2str(app.PRN_visual{1})]))
            
            mesh(app.SatAcq_2,app.acq_results_visual{2});
%             grid on;
            title(app.SatAcq_2,strcat(['C/A for PRN ' int2str(app.PRN_visual{2})]))

            mesh(app.SatAcq_3,app.acq_results_visual{3});
%             grid on;
            title(app.SatAcq_3,strcat(['C/A for PRN ' int2str(app.PRN_visual{3})]))

            mesh(app.SatAcq_4,app.acq_results_visual{4});
%             grid on;
            title(app.SatAcq_4,strcat(['C/A for PRN ' int2str(app.PRN_visual{4})]))

            mesh(app.SatAcq_5,app.acq_results_visual{5});
%             grid on;
            title(app.SatAcq_5,strcat(['C/A for PRN ' int2str(app.PRN_visual{5})]))

            mesh(app.SatAcq_6,app.acq_results_visual{6});
%             grid on;
            title(app.SatAcq_6,strcat(['C/A for PRN ' int2str(app.PRN_visual{6})]))

            mesh(app.SatAcq_7,app.acq_results_visual{7});
%             grid on;
            title(app.SatAcq_7,strcat(['C/A for PRN ' int2str(app.PRN_visual{7})]))

            mesh(app.SatAcq_8,app.acq_results_visual{8});
%             grid on;
            title(app.SatAcq_8,strcat(['C/A for PRN ' int2str(app.PRN_visual{8})]))

            mesh(app.SatAcq_9,app.acq_results_visual{9});
%             grid on;
            title(app.SatAcq_9,strcat(['C/A for PRN ' int2str(app.PRN_visual{9})]))

            mesh(app.SatAcq_10,app.acq_results_visual{10});
%             grid on;
            title(app.SatAcq_10,strcat(['C/A for PRN ' int2str(app.PRN_visual{10})]))

            mesh(app.SatAcq_11,app.acq_results_visual{11});
%             grid on;
            title(app.SatAcq_11,strcat(['C/A for PRN ' int2str(app.PRN_visual{11})]))

            mesh(app.SatAcq_12,app.acq_results_visual{12});
%             grid on;
            title(app.SatAcq_12,strcat(['C/A for PRN ' int2str(app.PRN_visual{12})]))
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Equivalent to main function 
            
            set(app.run_IQ_button,'visible', 'off'); % Allows play button to only appear after the uploaded file
            set(app.run_acquisition_button,'visible', 'off') % Allow play button to appear
            set(app.run_tracking_button,'visible', 'off') % Allow play button to appear
           

        end

        % Button pushed function: UPLOADIQFILEButton
        function UPLOADIQFILEButtonPushed(app, event)
             app.nav = NavTek;
            
             % Wait for iq_file to be uploaded
             if app.nav.fnameIQ
%                  app.nav.userfnameIQ(app.iq_file); % Update fnameIQ in NavTek class
%                  app.nav.fnameIQ = app.iq_file;
                 set(app.run_IQ_button,'visible', 'on') % Allow play button to appear
                 app.run_IQ_button.BackgroundColor = [0.00,1.00,0.00]; % For succesfull upload, the button turns green. 
             end   
        end

        % Image clicked function: run_IQ_button
        function run_IQ_buttonClicked(app, event)
            
%             disp(app.nav.fnameIQ)
            app.probeData(); % Plot IQ Characteristics
            
            app.TabGroup.SelectedTab = app.IQTab; % Opens the new tab for the IQ Characteristics
            pause(5)
           
            %% Make arrow visible in a bit
            set(app.run_acquisition_button,'visible', 'on') % Allow play button to appear
            app.run_acquisition_button.BackgroundColor = [0.00,1.00,0.00]; % Allow user to continue.

            
        end

        % Image clicked function: run_acquisition_button
        function run_acquisition_buttonImageClicked(app, event)
            
            fid = fopen(app.nav.fnameIQ, 'rb');
            
           if (fid > 0)
                %move to the start of the processing location
                fseek(fid, 2*app.nav.skipNumBytes, 'bof');

                %Find the number of samples per chipping sequence
                samples_per_code = round(app.nav.fsamp / (app.nav.fchip / app.nav.codeLength));

                %need to read in at least 42ms of data to get good course
                %aquisition
                codeLen = max(42, app.nav.NonCohTime + 2);
            
                %read the data to perform acquisition
                data = fread(fid, 2*codeLen*samples_per_code, app.nav.dataType)';
                data = data(1:2:end) + 1i .* data(2:2:end);

                disp("Aquiring satellites");
                %% Aquisition
                [acq_results, app.acq_results_visual, app.PRN_visual] = app.nav.aquisition(data, app.acq_results_visual, app.PRN_visual);
                
                if any(acq_results.carrFreq)
                    channel = app.nav.sortAcquisition(acq_results);
                    app.nav.dispFineCAResults(channel);

                else 
                    warning("No Satellites to track");
                    return;
                end
                %get rid of acquisiton plots to start tracking
                close all;
           end

           app.satellite_acquired_generate();
           app.TabGroup.SelectedTab = app.ACQUISITIONTab; % Opens the Tab for Acquisition
           
           %% Make arrow visible in a bit
           pause(5)
           set(app.run_tracking_button,'visible', 'on') % Allow play button to appear
           app.run_acquisition_button.BackgroundColor = [0.00,1.00,0.00]; % Allow user to continue.


        end

        % Image clicked function: run_tracking_button
        function run_tracking_buttonImageClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HOMETab = uitab(app.TabGroup);
            app.HOMETab.Title = 'HOME';
            app.WELCOMETOLOGPOSELabel = uilabel(app.HOMETab);
            app.WELCOMETOLOGPOSELabel.Text = 'WELCOME TO LOG POSE';
            app.StartbyuploadingaIQfileontherightLabel = uilabel(app.HOMETab);
            app.StartbyuploadingaIQfileontherightLabel.Text = 'Start by uploading a IQ file on the right.';
            app.ATOOLTOPOSTPROCESSYOUROWNPOSITIONLabel = uilabel(app.HOMETab);
            app.ATOOLTOPOSTPROCESSYOUROWNPOSITIONLabel.Text = 'A TOOL TO POST-PROCESS YOUR OWN POSITION.';
            app.UPLOADIQFILEButton = uibutton(app.HOMETab, 'push');
            app.UPLOADIQFILEButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADIQFILEButtonPushed, true);
            app.UPLOADIQFILEButton.Text = 'UPLOAD IQ FILE ';
            app.Image = uiimage(app.HOMETab);
            app.Image.ImageSource = 'car.png';
            app.run_IQ_button = uiimage(app.HOMETab);
            app.run_IQ_button.ImageClickedFcn = createCallbackFcn(app, @run_IQ_buttonClicked, true);
            app.run_IQ_button.ImageSource = 'play-button.png';
            app.IQTab = uitab(app.TabGroup);
            app.IQTab.Title = 'IQ';
            app.iq_frq = uiaxes(app.IQTab);
            title(app.iq_frq, 'Frequency domain plot')
            xlabel(app.iq_frq, 'Frequency (Mhz)')
            ylabel(app.iq_frq, 'Magnitude')
            zlabel(app.iq_frq, 'Z')
            app.time_Q = uiaxes(app.IQTab);
            title(app.time_Q, 'Time domain plot (Q)')
            xlabel(app.time_Q, 'Time (ms)')
            ylabel(app.time_Q, 'Amplitude')
            zlabel(app.time_Q, 'Z')
            app.time_I = uiaxes(app.IQTab);
            title(app.time_I, 'Time domain plot (I)')
            xlabel(app.time_I, 'Time (ms)')
            ylabel(app.time_I, 'Amplitude')
            zlabel(app.time_I, 'Z')
            app.hist_Q = uiaxes(app.IQTab);
            title(app.hist_Q, 'Histogram (Q)')
            xlabel(app.hist_Q, 'Bin')
            ylabel(app.hist_Q, 'Number in bin')
            zlabel(app.hist_Q, 'Z')
            app.hist_I = uiaxes(app.IQTab);
            title(app.hist_I, 'Histogram (I)')
            xlabel(app.hist_I, 'Bin')
            ylabel(app.hist_I, 'Number in bin')
            zlabel(app.hist_I, 'Z')
            app.IQCharacteristicsLabel = uilabel(app.IQTab);
            app.IQCharacteristicsLabel.Text = 'IQ Characteristics';
            app.run_acquisition_button = uiimage(app.IQTab);
            app.run_acquisition_button.ImageClickedFcn = createCallbackFcn(app, @run_acquisition_buttonImageClicked, true);
            app.run_acquisition_button.ImageSource = 'play-button.png';
            app.ACQUISITIONTab = uitab(app.TabGroup);
            app.ACQUISITIONTab.Title = 'ACQUISITION';
            app.SatAcq = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq, 'C/A for PRN')
            xlabel(app.SatAcq, 'f_d bin [Hz]')
            ylabel(app.SatAcq, 't_s bin [sec]')
            zlabel(app.SatAcq, 'Z')
            app.SatAcq_2 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_2, 'C/A for PRN')
            xlabel(app.SatAcq_2, 'f_d bin [Hz]')
            ylabel(app.SatAcq_2, 't_s bin [sec]')
            zlabel(app.SatAcq_2, 'Z')
            app.SatAcq_3 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_3, 'C/A for PRN')
            xlabel(app.SatAcq_3, 'f_d bin [Hz]')
            ylabel(app.SatAcq_3, 't_s bin [sec]')
            zlabel(app.SatAcq_3, 'Z')
            app.SatAcq_4 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_4, 'C/A for PRN')
            xlabel(app.SatAcq_4, 'f_d bin [Hz]')
            ylabel(app.SatAcq_4, 't_s bin [sec]')
            zlabel(app.SatAcq_4, 'Z')
            app.SatAcq_5 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_5, 'C/A for PRN')
            xlabel(app.SatAcq_5, 'f_d bin [Hz]')
            ylabel(app.SatAcq_5, 't_s bin [sec]')
            zlabel(app.SatAcq_5, 'Z')
            app.SatAcq_6 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_6, 'C/A for PRN')
            xlabel(app.SatAcq_6, 'f_d bin [Hz]')
            ylabel(app.SatAcq_6, 't_s bin [sec]')
            zlabel(app.SatAcq_6, 'Z')
            app.SatAcq_7 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_7, 'C/A for PRN')
            xlabel(app.SatAcq_7, 'f_d bin [Hz]')
            ylabel(app.SatAcq_7, 't_s bin [sec]')
            zlabel(app.SatAcq_7, 'Z')
            app.SatAcq_8 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_8, 'C/A for PRN')
            xlabel(app.SatAcq_8, 'f_d bin [Hz]')
            ylabel(app.SatAcq_8, 't_s bin [sec]')
            zlabel(app.SatAcq_8, 'Z')
            app.SatAcq_9 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_9, 'C/A for PRN')
            xlabel(app.SatAcq_9, 'f_d bin [Hz]')
            ylabel(app.SatAcq_9, 't_s bin [sec]')
            zlabel(app.SatAcq_9, 'Z')
            app.SatAcq_10 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_10, 'C/A for PRN')
            xlabel(app.SatAcq_10, 'f_d bin [Hz]')
            ylabel(app.SatAcq_10, 't_s bin [sec]')
            zlabel(app.SatAcq_10, 'Z')
            app.SatAcq_11 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_11, 'C/A for PRN')
            xlabel(app.SatAcq_11, 'f_d bin [Hz]')
            ylabel(app.SatAcq_11, 't_s bin [sec]')
            zlabel(app.SatAcq_11, 'Z')
            app.SatAcq_12 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_12, 'C/A for PRN')
            xlabel(app.SatAcq_12, 'f_d bin [Hz]')
            ylabel(app.SatAcq_12, 't_s bin [sec]')
            zlabel(app.SatAcq_12, 'Z')
            app.SatelliteSignalAcquisitionLabel = uilabel(app.ACQUISITIONTab);
            app.SatelliteSignalAcquisitionLabel.Text = 'Satellite Signal Acquisition';
            app.run_tracking_button = uiimage(app.ACQUISITIONTab);
            app.run_tracking_button.ImageClickedFcn = createCallbackFcn(app, @run_tracking_buttonImageClicked, true);
            app.run_tracking_button.ImageSource = 'play-button.png';
            app.TRACKINGTab = uitab(app.TabGroup);
            app.TRACKINGTab.Title = 'TRACKING';
            app.SATELLITEPOSTab = uitab(app.TabGroup);
            app.SATELLITEPOSTab.Title = 'SATELLITE POS';
            app.USERPOSTab = uitab(app.TabGroup);
            app.USERPOSTab.Title = 'USER POS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logpose_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end